classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ConsolePanel                    matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        Panel                           matlab.ui.container.Panel
        OutputLabel                     matlab.ui.control.Label
        Agilent33220AToolLabel          matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        Lamp                            matlab.ui.control.Lamp
        TabGroup                        matlab.ui.container.TabGroup
        BasicTab_2                      matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        DropDown_4                      matlab.ui.control.DropDown
        DropDown_3                      matlab.ui.control.DropDown
        DropDown_2                      matlab.ui.control.DropDown
        DropDown                        matlab.ui.control.DropDown
        PeriodSpinner                   matlab.ui.control.Spinner
        PeriodSpinnerLabel              matlab.ui.control.Label
        FrequencySpinner                matlab.ui.control.Spinner
        FrequencySpinnerLabel           matlab.ui.control.Label
        OffsetSpinner                   matlab.ui.control.Spinner
        OffsetSpinnerLabel              matlab.ui.control.Label
        AmplitudePPSpinner              matlab.ui.control.Spinner
        AmplitudePPSpinnerLabel         matlab.ui.control.Label
        ChooseastateLabel               matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ArbButton                       matlab.ui.control.RadioButton
        PulseButton                     matlab.ui.control.RadioButton
        RampButton                      matlab.ui.control.RadioButton
        SquareButton                    matlab.ui.control.RadioButton
        SinusoidButton                  matlab.ui.control.RadioButton
        ArbTab                          matlab.ui.container.Tab
        ChannelSpinner                  matlab.ui.control.Spinner
        ChannelSpinnerLabel             matlab.ui.control.Label
        SampleratekHzSpinner            matlab.ui.control.Spinner
        SampleratekHzSpinnerLabel       matlab.ui.control.Label
        UploadarbfileLabel              matlab.ui.control.Label
        LoaddataButton                  matlab.ui.control.Button
        ConnectionTab                   matlab.ui.container.Tab
        UpdateButton                    matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        OutputBuffersizeEditField       matlab.ui.control.EditField
        OutputBuffersizeEditFieldLabel  matlab.ui.control.Label
        VisaAddressEditField            matlab.ui.control.EditField
        VisaAddressEditFieldLabel       matlab.ui.control.Label
        CreditsTab                      matlab.ui.container.Tab
        LinkButton                      matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
    end


    properties (Access = private)
        waveformGen
        visaAddress
        myTimer
        state  %type of state
        amplitude
        offset
        frequency
        period
        connected
        result
        arbData
        sampleRate
        channel
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("C:\Users\dlvalencia\Documents\MATLAB");

            [app.waveformGen, app.visaAddress, app.connected] = initialize;

            if app.connected
                app.result = "Successfully connected to "+app.visaAddress;
                app.TextArea.Value = app.result;
            else
                app.result = "Could not connect to waveform generator";
                app.TextArea.Value = app.result;
            end


            app.state = "SINUSOID";

            app.amplitude = app.AmplitudePPSpinner.Value * str2double(app.DropDown.Value);

            app.offset = app.OffsetSpinner.Value * str2double(app.DropDown_2.Value);

            app.frequency =  app.FrequencySpinner.Value * str2double(app.DropDown_3.Value);

            app.period = app.PeriodSpinner.Value * str2double(app.DropDown_4.Value);

            app.sampleRate = app.SampleratekHzSpinner.Value * 1000;

            app.channel = app.ChannelSpinner.Value;

            if app.connected
                app.Lamp_2.Color = "blue";
            else
                app.Lamp_2.Color = "#808080";
            end

        end

        % Value changed function: FrequencySpinner
        function FrequencySpinnerValueChanged(app, event)
            value = app.FrequencySpinner.Value;
            period_ = 1/value;
            app.PeriodSpinner.Value = period_;

            app.frequency = value * str2double(app.DropDown_3.Value);

            outputwaveform_function(app.waveformGen,app.state,app.frequency,app.offset,app.amplitude);

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            openclose(app.waveformGen, value, app.connected);
            if value ==1
                app.Lamp.Color="Green";
            else
                app.Lamp.Color="Red";
            end
        end

        % Value changed function: AmplitudePPSpinner
        function AmplitudePPSpinnerValueChanged(app, event)
            value = app.AmplitudePPSpinner.Value;
            app.amplitude = value * str2double(app.DropDown.Value);
           
            outputwaveform_function(app.waveformGen, app.state, app.frequency, app.offset, app.amplitude);
            
        end

        % Value changed function: OffsetSpinner
        function OffsetSpinnerValueChanged(app, event)
            value = app.OffsetSpinner.Value;
            app.offset = value * str2double(app.DropDown_2.Value);

            outputwaveform_function(app.waveformGen,app.state,app.frequency,app.offset,app.amplitude);
        end

        % Value changed function: PeriodSpinner
        function PeriodSpinnerValueChanged(app, event)
            value = app.PeriodSpinner.Value;

            frequency_ = 1/value;
            app.FrequencySpinner.Value = frequency_;

            app.period = value * str2double(app.DropDown_4.Value);

            outputwaveform_function(app.waveformGen,app.state,1/app.period,app.offset,app.amplitude);
        end

        % Callback function
        function ListBox_4ValueChanged(app, event)

        end

        % Callback function
        function ListBox_3ValueChanged(app, event)

        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;

            if app.DropDown_3.Value == "1"
                app.DropDown_4.Value = "1";
            end

            if app.DropDown_3.Value == "1000"
                app.DropDown_4.Value = "0.001";
            end

            if app.DropDown_3.Value == "1000000"
                app.DropDown_4.Value = "0.000001";
            end

            app.frequency = app.FrequencySpinner.Value * str2double(value);

            outputwaveform_function(app.waveformGen,app.state,app.frequency,app.offset,app.amplitude);

        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            value = app.DropDown_4.Value;

            if value == "1"
                app.DropDown_3.Value = "1";
            end

            if value == "0.001"
                app.DropDown_3.Value = "1000";
            end

            if value == "0.000001"
                app.DropDown_3.Value = "1000000";
            end

            app.period = app.PeriodSpinner.Value * str2double(value);

            outputwaveform_function(app.waveformGen,app.state,1/app.period,app.offset,app.amplitude);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;

            if strcmp(selectedButton.Text, "Arb")
                app.TabGroup.SelectedTab = app.ArbTab;
          
                return
            end

            if strcmp(selectedButton.Text, "Sinusoid")
                app.state = "SINUSOID";
            elseif strcmp(selectedButton.Text, "Square")
                app.state = "SQUARE";
            elseif strcmp(selectedButton.Text, "Ramp")
                app.state = "RAMP";
            elseif strcmp(selectedButton.Text, "Pulse")
                app.state = "PULSE";
            elseif strcmp(selectedButton.Text, "Noise")
                app.state = "NOISE";
            end

            outputwaveform_function(app.waveformGen, app.state, app.frequency, app.offset, app.amplitude);

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;

            app.amplitude = app.AmplitudePPSpinner.Value * str2double(value);

            outputwaveform_function(app.waveformGen,app.state,app.frequency,app.offset,app.amplitude);


        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;

            app.offset = app.OffsetSpinner.Value * str2double(value);

            outputwaveform_function(app.waveformGen,app.state,app.frequency,app.offset,app.amplitude);

        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            if ~isempty(app.VisaAddressEditField.Value) && ~isempty(app.OutputBuffersizeEditField.Value)

                [app.waveformGen,app.connected, app.result] = updateAddress(app.VisaAddressEditField.Value, app.OutputBuffersizeEditField.Value);
                app.TextArea.Value = [app.TextArea.Value; {num2str(app.result)}];

                if app.connected
                    app.Lamp_2.Color = "blue";
                else
                    app.Lamp_2.Color = "#808080";
                end

            else
                app.result = "Please fill in all input boxes";
                app.TextArea.Value = [app.TextArea.Value; {num2str(app.result)}];
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            close(INSTRFIND);
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            uiopen;

            app.ButtonGroup.SelectedObject = app.ArbButton;


            try
                disp('Before calling outputArb');
                outputArb(app.waveformGen, app.sampleRate,data);
                disp('After calling outputArb');
                app.result = "Successfully finished loaded arb data";
                app.TextArea.Value = [app.TextArea.Value; {num2str(app.result)}];
            catch

                app.result = "Error loading arb data";
                app.TextArea.Value = [app.TextArea.Value; {num2str(app.result)}];
            end

        end

        % Callback function: not associated with a component
        function ResetButtonPushed(app, event)
            arbReset();
        end

        % Value changed function: SampleratekHzSpinner
        function SampleratekHzSpinnerValueChanged(app, event)
            value = app.SampleratekHzSpinner.Value;
            
            app.sampleRate = value * 1000;
        end

        % Value changed function: ChannelSpinner
        function ChannelSpinnerValueChanged(app, event)
            value = app.ChannelSpinner.Value;
            
            app.channel = value;
        end

        % Button pushed function: LinkButton
        function LinkButtonPushed(app, event)
          
                web('https://www.linkedin.com/in/daniel-valencia-674729132?original_referer=https%3A%2F%2Fwww.linkedin.com%2Fin%2Fdaniel-valencia-674729132', '-browser');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicTab_2 = uitab(app.TabGroup);
            app.BasicTab_2.Title = 'Basic';
            app.ButtonGroup = uibuttongroup(app.BasicTab_2);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SinusoidButton = uiradiobutton(app.ButtonGroup);
            app.SinusoidButton.Text = 'Sinusoid';
            app.SinusoidButton.Value = true;
            app.SquareButton = uiradiobutton(app.ButtonGroup);
            app.SquareButton.Text = 'Square';
            app.RampButton = uiradiobutton(app.ButtonGroup);
            app.RampButton.Text = 'Ramp';
            app.PulseButton = uiradiobutton(app.ButtonGroup);
            app.PulseButton.Text = 'Pulse';
            app.ArbButton = uiradiobutton(app.ButtonGroup);
            app.ArbButton.Text = 'Arb';
            app.ChooseastateLabel = uilabel(app.BasicTab_2);
            app.ChooseastateLabel.Text = 'Choose a state:';
            app.Panel_2 = uipanel(app.BasicTab_2);
            app.AmplitudePPSpinnerLabel = uilabel(app.Panel_2);
            app.AmplitudePPSpinnerLabel.Text = 'Amplitude PP ';
            app.AmplitudePPSpinner = uispinner(app.Panel_2);
            app.AmplitudePPSpinner.Step = 0.1;
            app.AmplitudePPSpinner.Limits = [-10 10];
            app.AmplitudePPSpinner.ValueChangedFcn = createCallbackFcn(app, @AmplitudePPSpinnerValueChanged, true);
            app.AmplitudePPSpinner.Value = 5;
            app.OffsetSpinnerLabel = uilabel(app.Panel_2);
            app.OffsetSpinnerLabel.Text = 'Offset';
            app.OffsetSpinner = uispinner(app.Panel_2);
            app.OffsetSpinner.Step = 0.1;
            app.OffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @OffsetSpinnerValueChanged, true);
            app.FrequencySpinnerLabel = uilabel(app.Panel_2);
            app.FrequencySpinnerLabel.Text = 'Frequency';
            app.FrequencySpinner = uispinner(app.Panel_2);
            app.FrequencySpinner.Step = 0.1;
            app.FrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencySpinnerValueChanged, true);
            app.FrequencySpinner.Value = 100;
            app.PeriodSpinnerLabel = uilabel(app.Panel_2);
            app.PeriodSpinnerLabel.Text = 'Period';
            app.PeriodSpinner = uispinner(app.Panel_2);
            app.PeriodSpinner.Step = 0.1;
            app.PeriodSpinner.ValueChangedFcn = createCallbackFcn(app, @PeriodSpinnerValueChanged, true);
            app.PeriodSpinner.Value = 0.01;
            app.DropDown = uidropdown(app.Panel_2);
            app.DropDown.Items = {'V', 'mV'};
            app.DropDown.ItemsData = {'1', '0.001'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '1';
            app.DropDown_2 = uidropdown(app.Panel_2);
            app.DropDown_2.Items = {'V', 'mV'};
            app.DropDown_2.ItemsData = {'1', '0.001'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '1';
            app.DropDown_3 = uidropdown(app.Panel_2);
            app.DropDown_3.Items = {'Hz', 'kHz', 'MHz'};
            app.DropDown_3.ItemsData = {'1', '1000', '1000000'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = '1';
            app.DropDown_4 = uidropdown(app.Panel_2);
            app.DropDown_4.Items = {'sec', 'msec', 'µsec'};
            app.DropDown_4.ItemsData = {'1', '0.001', '0.000001'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = '1';
            app.ArbTab = uitab(app.TabGroup);
            app.ArbTab.Title = 'Arb';
            app.LoaddataButton = uibutton(app.ArbTab, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.UploadarbfileLabel = uilabel(app.ArbTab);
            app.UploadarbfileLabel.Text = 'Upload arb file:';
            app.SampleratekHzSpinnerLabel = uilabel(app.ArbTab);
            app.SampleratekHzSpinnerLabel.Text = 'Sample rate (kHz)';
            app.SampleratekHzSpinner = uispinner(app.ArbTab);
            app.SampleratekHzSpinner.Limits = [0 65.535];
            app.SampleratekHzSpinner.ValueChangedFcn = createCallbackFcn(app, @SampleratekHzSpinnerValueChanged, true);
            app.SampleratekHzSpinner.Value = 24;
            app.ChannelSpinnerLabel = uilabel(app.ArbTab);
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.ChannelSpinner = uispinner(app.ArbTab);
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelSpinnerValueChanged, true);
            app.ChannelSpinner.Value = 1;
            app.ConnectionTab = uitab(app.TabGroup);
            app.ConnectionTab.Title = 'Connection';
            app.VisaAddressEditFieldLabel = uilabel(app.ConnectionTab);
            app.VisaAddressEditFieldLabel.Text = 'Visa Address:';
            app.VisaAddressEditField = uieditfield(app.ConnectionTab, 'text');
            app.VisaAddressEditField.Value = 'TCPIP0::130.191.161.194::inst0::INSTR';
            app.OutputBuffersizeEditFieldLabel = uilabel(app.ConnectionTab);
            app.OutputBuffersizeEditFieldLabel.Text = 'Output Buffersize:';
            app.OutputBuffersizeEditField = uieditfield(app.ConnectionTab, 'text');
            app.OutputBuffersizeEditField.Value = '100e6';
            app.Lamp_2 = uilamp(app.ConnectionTab);
            app.UpdateButton = uibutton(app.ConnectionTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.CreditsTab = uitab(app.TabGroup);
            app.CreditsTab.Title = 'Credits';
            app.TextArea_2 = uitextarea(app.CreditsTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'WARG v1.0'; ''; 'Director - Daniel Valencia'; ''; 'Developers - Po-Jui (frontend), Jack (backend)'; ''; ''; 'Designed at SDSU ECE E218 VLSI Design and Test Lab'};
            app.LinkButton = uibutton(app.CreditsTab, 'push');
            app.LinkButton.ButtonPushedFcn = createCallbackFcn(app, @LinkButtonPushed, true);
            app.LinkButton.Text = 'Link';
            app.Panel = uipanel(app.UIFigure);
            app.Lamp = uilamp(app.Panel);
            app.Switch = uiswitch(app.Panel, 'slider');
            app.Switch.Items = {'', ''};
            app.Switch.ItemsData = [0 1];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 0;
            app.Agilent33220AToolLabel = uilabel(app.Panel);
            app.Agilent33220AToolLabel.Text = 'Agilent 33220A Tool';
            app.OutputLabel = uilabel(app.Panel);
            app.OutputLabel.Text = 'Output';
            app.ConsolePanel = uipanel(app.UIFigure);
            app.ConsolePanel.Title = 'Console';
            app.TextArea = uitextarea(app.ConsolePanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end